classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DuvalTriangle1Label  matlab.ui.control.Label
        ValuesinppmLabel     matlab.ui.control.Label
        AnalyzeFaultsButton  matlab.ui.control.Button
        ResultTextArea       matlab.ui.control.TextArea
        ResultLabel          matlab.ui.control.Label
        CO2EditField         matlab.ui.control.NumericEditField
        CO2EditFieldLabel    matlab.ui.control.Label
        COEditField          matlab.ui.control.NumericEditField
        COEditFieldLabel     matlab.ui.control.Label
        C2H2EditField        matlab.ui.control.NumericEditField
        C2H2EditFieldLabel   matlab.ui.control.Label
        C2H4EditField        matlab.ui.control.NumericEditField
        C2H4EditFieldLabel   matlab.ui.control.Label
        C2H6EditField        matlab.ui.control.NumericEditField
        C2H6EditFieldLabel   matlab.ui.control.Label
        CH4EditField         matlab.ui.control.NumericEditField
        CH4EditFieldLabel    matlab.ui.control.Label
        H2EditField          matlab.ui.control.NumericEditField
        H2EditFieldLabel     matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeFaultsButton
        function AnalyzeFaultsButtonPushed(app, event)
            % ---------------------------------------------------------
            % INPUTS
            % ---------------------------------------------------------
            h2 = app.H2EditField.Value;
            ch4 = app.CH4EditField.Value;
            c2h6 = app.C2H6EditField.Value;
            c2h4 = app.C2H4EditField.Value;
            c2h2 = app.C2H2EditField.Value;
            co = app.COEditField.Value;
            co2 = app.CO2EditField.Value; 

            % Initialize Output Text
            final_report = "===== DGA DIAGNOSTIC REPORT =====" + newline + newline;

            % ---------------------------------------------------------
            % TDCG ANALYSIS
            % ---------------------------------------------------------
            tdcg = h2 + ch4 + c2h6 + c2h4 + c2h2 + co;
            final_report = final_report + sprintf("Total Dissolved Combustible Gas: %.0f ppm\n", tdcg);

            if tdcg < 720
                status = "Condition 1: Normal";
            elseif tdcg <= 1920
                status = "Condition 2: Caution (Watch Trends)";
            elseif tdcg <= 4630
                status = "Condition 3: High Risk";
            else
                status = "Condition 4: IMMEDIATE ACTION REQUIRED";
            end
            final_report = final_report + "Status: " + status + newline + newline;

            % ---------------------------------------------------------
            % ROGERS RATIO METHOD 
            % ---------------------------------------------------------
            % R1: Acetylene / Ethylene
            if c2h4 > 0, r1 = c2h2 / c2h4;
            elseif c2h2 == 0, r1 = 0;       % 0/0 = Normal
            else, r1 = 999;                 % High/0 = Fault
            end

            % R2: Methane / Hydrogen
            if h2 > 0, r2 = ch4 / h2;
            elseif ch4 == 0, r2 = 0;
            else, r2 = 999; 
            end

            % R5: Ethylene / Ethane
            if c2h6 > 0, r5 = c2h4 / c2h6;
            elseif c2h4 == 0, r5 = 0;
            else, r5 = 999; 
            end

            final_report = final_report + "---> Rogers Ratio Method <---" + newline;
            final_report = final_report + sprintf("R1: %.2f | R2: %.2f | R5: %.2f\n", r1, r2, r5);

            if (r1 < 0.1) && (r2 >= 0.1 && r2 <= 1.0) && (r5 < 1.0)
                rr_res = "Normal working condition";
            elseif (r1 < 0.1) && (r2 < 0.1) && (r5 < 1.0)
                rr_res = "Possible Partial Discharge";
            elseif (r1 >= 0.1 && r1 <= 3.0) && (r2 >= 0.1 && r2 <= 1.0) && (r5 > 3.0)
                rr_res = "High-energy Discharge (Arcing)";
            elseif (r1 < 0.1) && (r2 >= 0.1 && r2 <= 1.0) && (r5 >= 1.0 && r5 <= 3.0)
                rr_res = "Low Temp Thermal Fault (<300C)";
            elseif (r1 < 0.1) && (r2 > 1.0) && (r5 >= 1.0 && r5 <= 3.0)
                rr_res = "Medium Temp Thermal Fault (300-700C)";
            elseif (r1 < 0.1) && (r2 > 1.0) && (r5 > 3.0)
                rr_res = "High Temp Thermal Fault (>700C)";
            else
                rr_res = "Undetermined (No standard match)";
            end
            final_report = final_report + "Diagnosis: " + rr_res + newline + newline;

            % ---------------------------------------------------------
            % KEY GAS METHOD
            % ---------------------------------------------------------
            final_report = final_report + "---> Key Gas Method <---" + newline;

            if tdcg > 0
                p_h2 = (h2/tdcg)*100; p_ch4 = (ch4/tdcg)*100; p_c2h6 = (c2h6/tdcg)*100;
                p_c2h4 = (c2h4/tdcg)*100; p_c2h2 = (c2h2/tdcg)*100; p_co = (co/tdcg)*100;
            else
                p_h2=0; p_ch4=0; p_c2h6=0; p_c2h4=0; p_c2h2=0; p_co=0;
            end

            kg_result = "Normal / Inconclusive";
            if (p_c2h2 >= 5) && (c2h2 > 0)
                kg_result = "High-Energy Discharge (Arcing) [Acetylene]";
            elseif (p_c2h4 > 20) && (c2h4 > c2h6)
                kg_result = "Thermal Fault in Oil (>700 C) [Ethylene]";
            elseif (p_co > 80)
                kg_result = "Overheating of Insulation Paper [CO]";
            elseif (p_c2h6 > p_ch4) && (p_c2h6 > p_c2h4)
                kg_result = "Low-temp Thermal [Ethane]";
            elseif (p_ch4 > p_h2) && (p_ch4 > p_c2h4)
                kg_result = "Low-temp Thermal (<300 C) [Methane]";
            elseif (p_h2 > p_ch4) && (p_h2 > p_c2h4)
                kg_result = "Partial Discharge (Corona) [Hydrogen]";
            end
            final_report = final_report + "Diagnosis: " + kg_result + newline + newline;

            % ---------------------------------------------------------
            % CO2/CO RATIO
            % ---------------------------------------------------------
            final_report = final_report + "---> CO2/CO Ratio Analysis <---" + newline;
            if co > 0
                ratio = co2/co;
                final_report = final_report + sprintf("Ratio: %.2f\n", ratio);
                if ratio < 3, co_res = "Severe paper degradation (Carbonization)";
                elseif ratio > 10, co_res = "Normal paper aging / Mild overheating";
                else, co_res = "Healthy insulation / Standard operation";
                end
            else
                co_res = "N/A (CO is zero)";
            end
            final_report = final_report + "Diagnosis: " + co_res + newline + newline;

            % ---------------------------------------------------------
            % DUVAL TRIANGLE 1 
            % ---------------------------------------------------------
            final_report = final_report + "---> Duval Triangle 1 Method <---" + newline;
            
            % Define DT1 Polygons
            top = [0.5, 0.866]; right = [1, 0]; left = [0, 0];
            a=[0.49, 0.849]; b=[0.51, 0.849]; c=[0.48, 0.831]; d=[0.58, 0.658];
            e=[0.6, 0.693]; f=[0.73, 0.398]; g=[0.75, 0.433]; h=[0.675, 0.303];
            I=[0.85, 0]; J=[0.71, 0]; k=[0.555, 0.268]; l=[0.635, 0.407];
            m=[0.55, 0.554]; n=[0.435, 0.753]; o=[0.23, 0];

            PD_x = [top(1), b(1), a(1)]; PD_y = [top(2), b(2), a(2)];
            T1_x = [a(1), b(1), e(1), d(1), c(1)]; T1_y = [a(2), b(2), e(2), d(2), c(2)];
            T2_x = [d(1), e(1), g(1), f(1)]; T2_y = [d(2), e(2), g(2), f(2)];
            T3_x = [f(1), g(1), right(1), I(1), h(1)]; T3_y = [f(2), g(2), right(2), I(2), h(2)];
            D1_x = [n(1), m(1), o(1), left(1)]; D1_y = [n(2), m(2), o(2), left(2)];
            D2_x = [m(1), l(1), k(1), J(1), o(1)]; D2_y = [m(2), l(2), k(2), J(2), o(2)];
            DT_x = [c(1), d(1), f(1), h(1), I(1), J(1), k(1), l(1), m(1), n(1)];
            DT_y = [c(2), d(2), f(2), h(2), I(2), J(2), k(2), l(2), m(2), n(2)];

            gas_sum = ch4 + c2h4 + c2h2;
            dt_fault = "N/A (Gas sum is 0)";
            X_pt = -1; Y_pt = -1;

            if gas_sum > 0
                p_ch4_1 = (ch4/gas_sum); p_c2h4_1 = (c2h4/gas_sum);
                X_pt = ((p_c2h4_1 / 0.866) + (p_ch4_1 / 1.732)) * 0.866;
                Y_pt = p_ch4_1 * 0.866;

                if inpolygon(X_pt, Y_pt, PD_x, PD_y), dt_fault = 'PD: Partial Discharge';
                elseif inpolygon(X_pt, Y_pt, T1_x, T1_y), dt_fault = 'T1: Low Temp Thermal (<300C)';
                elseif inpolygon(X_pt, Y_pt, T2_x, T2_y), dt_fault = 'T2: Medium Temp Thermal (300-700C)';
                elseif inpolygon(X_pt, Y_pt, T3_x, T3_y), dt_fault = 'T3: High Temp Thermal (>700C)';
                elseif inpolygon(X_pt, Y_pt, D1_x, D1_y), dt_fault = 'D1: Low Energy Discharge';
                elseif inpolygon(X_pt, Y_pt, D2_x, D2_y), dt_fault = 'D2: High Energy Discharge';
                elseif inpolygon(X_pt, Y_pt, DT_x, DT_y), dt_fault = 'DT: Mix of Thermal/Electrical';
                else, dt_fault = 'Undetermined / Boundary';
                end
            end
            final_report = final_report + "Diagnosis: " + string(dt_fault) + newline + newline;
            app.ResultTextArea.Value = final_report;

            % ---------------------------------------------------------
            % PLOT DUVAL TRIANGLE 1 (In App)
            % ---------------------------------------------------------
            cla(app.UIAxes); hold(app.UIAxes, 'on'); axis(app.UIAxes, 'equal'); axis(app.UIAxes, 'off');
            fill(app.UIAxes, PD_x, PD_y, [0.85 0.95 1.0], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            fill(app.UIAxes, T1_x, T1_y, [1.0 0.68 0.69], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            fill(app.UIAxes, T2_x, T2_y, [1.0 0.80 0.00], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            fill(app.UIAxes, T3_x, T3_y, [0.25 0.25 0.25], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            fill(app.UIAxes, D1_x, D1_y, [0.00 0.81 0.88], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            fill(app.UIAxes, D2_x, D2_y, [0.15 0.32 0.65], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            fill(app.UIAxes, DT_x, DT_y, [0.83 0.33 0.64], 'EdgeColor', '#24273a', 'FaceAlpha', 0.5);
            
            % Zone Labels
            text(app.UIAxes, 0.5, 0.855, 'PD', 'FontSize', 8, 'HorizontalAlignment', 'center');
            text(app.UIAxes, 0.55, 0.75, 'T1', 'FontSize', 8, 'HorizontalAlignment', 'center');
            text(app.UIAxes, 0.65, 0.55, 'T2', 'FontSize', 8, 'HorizontalAlignment', 'center');
            text(app.UIAxes, 0.85, 0.20, 'T3', 'FontSize', 8, 'HorizontalAlignment', 'center', 'Color', 'w');
            text(app.UIAxes, 0.25, 0.20, 'D1', 'FontSize', 8, 'HorizontalAlignment', 'center');
            text(app.UIAxes, 0.45, 0.20, 'D2', 'FontSize', 8, 'HorizontalAlignment', 'center', 'Color', 'w');
            text(app.UIAxes, 0.65, 0.35, 'DT', 'FontSize', 8, 'HorizontalAlignment', 'center');

            if gas_sum > 0
                plot(app.UIAxes, X_pt, Y_pt, 'ko', 'MarkerSize', 6, 'MarkerFaceColor', 'r', 'LineWidth', 1.5);
                text(app.UIAxes, X_pt, Y_pt+0.05, 'FAULT', 'Color', 'r', 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
            end
            
            text(app.UIAxes, 0.5, 0.92, '100% CH_4', 'HorizontalAlignment', 'center');
            text(app.UIAxes, -0.05, 0, '100% C_2H_2', 'HorizontalAlignment', 'right');
            text(app.UIAxes, 1.05, 0, '100% C_2H_4', 'HorizontalAlignment', 'left');
            hold(app.UIAxes, 'off');

            % ---------------------------------------------------------
            % PLOTTING DUVAL TRIANGLE 4 (Popup Window)
            % ---------------------------------------------------------

            % Shows DT4 only if fault is PD, T1, or T2
            if any(strcmp(dt_fault, {'PD: Partial Discharge', 'T1: Low Temp Thermal (<300C)', 'T2: Medium Temp Thermal (300-700C)'}))
                
                % Coordinates (H2 Top, CH4 Right, C2H6 Left)
                tri = @(h, m, e) [ (m + 0.5*h), (h * sqrt(3)/2) ];
                
                % Creating a new Figure for DT4
                f = figure('Name', 'Duval Triangle 4', 'NumberTitle', 'off', 'Color', '#24273a', 'Position', [100, 100, 500, 450]);
                ax = axes(f); hold(ax, 'on'); axis(ax, 'equal'); axis(ax, 'off');
                title(ax, 'Duval Triangle 4 (Temp Zone is shown)');

                % Polygons
                p_pd = [tri(98, 2, 0); tri(97, 2, 1); tri(84, 15, 1); tri(85, 15, 0)];
                p_nd = [tri(54, 0, 46); tri(9, 45, 46); tri(9, 0, 91)];
                p_o  = [tri(9, 0, 91); tri(9, 61, 30); tri(0, 70, 30); tri(0, 0, 100)];
                p_c  = [tri(0, 100, 0); tri(0, 70, 30); tri(15, 55, 30); tri(15, 61, 24); tri(40, 36, 24); tri(64, 36, 0)];
                p_s  = [tri(98, 2, 0); tri(97, 2, 1); tri(84, 15, 1); tri(85, 15, 0); tri(64, 36, 0); tri(40, 36, 24); ...
                        tri(15, 61, 24); tri(15, 55, 30); tri(9, 61, 30); tri(9, 45, 46); tri(54, 0, 46); tri(100, 0, 0)];
                
                % Filling Polygons
                fill(ax, p_s(:,1), p_s(:,2),  [0.95, 0.95, 1.0], 'EdgeColor', 'k');
                fill(ax, p_pd(:,1), p_pd(:,2),[0.8, 0.9, 1.0], 'EdgeColor', 'k');
                fill(ax, p_nd(:,1), p_nd(:,2),[0.9, 1.0, 0.9], 'EdgeColor', 'k');
                fill(ax, p_o(:,1), p_o(:,2),  [1.0, 0.9, 0.6], 'EdgeColor', 'k');
                fill(ax, p_c(:,1), p_c(:,2),  [0.6, 0.6, 0.6], 'EdgeColor', 'k');

                % Labels
                tloc = @(h, m, str) text(ax, (m + 0.5*h), (h * sqrt(3)/2), str, 'Color', 'r', 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
                tloc(92, 9, 'PD'); tloc(50, 20, 'S'); tloc(25, 10, 'ND'); tloc(4, 30, 'O'); tloc(15, 70, 'C');
                
                text(ax, 23, 48, '% H_2', 'Rotation', 60, 'HorizontalAlignment', 'center', 'FontSize', 10);
                text(ax, 75, 50, '% CH_4', 'Rotation', -60, 'HorizontalAlignment', 'left', 'FontSize', 10);
                text(ax, 50, -5, '% C_2H_6', 'HorizontalAlignment', 'center', 'FontSize', 10);
                
                % Point
                sum4 = h2 + ch4 + c2h6;
                if sum4 > 0
                    pt = tri((h2/sum4)*100, (ch4/sum4)*100, (c2h6/sum4)*100);
                    plot(ax, pt(1), pt(2), 'ro', 'MarkerSize', 6, 'MarkerFaceColor', 'b', 'LineWidth', 1.5);
                    text(ax, pt(1)+2, pt(2), 'FAULT', 'BackgroundColor', 'w', 'EdgeColor', 'k');
                end
                
                % Main Outline
                Top=tri(100,0,0); Right=tri(0,100,0); Left=tri(0,0,100);
                plot(ax, [Top(1) Left(1) Right(1) Top(1)], [Top(2) Left(2) Right(2) Top(2)], 'k', 'LineWidth', 1.5);
            end

            % ---------------------------------------------------------
            % PLOTTING DUVAL TRIANGLE 5 (Popup Window)
            % ---------------------------------------------------------

            % Shows DT5 if fault is T2 or T3 (High Temperature Faults)
            if any(strcmp(dt_fault, {'T2: Medium Temp Thermal (300-700C)', 'T3: High Temp Thermal (>700C)'}))
                
                % Percentages for DT5
                sum5 = ch4 + c2h4 + c2h6;
                
                if sum5 > 0
                    p_ch4_5  = (ch4 / sum5) * 100;
                    p_c2h4_5 = (c2h4 / sum5) * 100;
                    p_c2h6_5 = (c2h6 / sum5) * 100; %#ok<NASGU>
                    
                    % Figure
                    f5 = figure('Name', 'Duval Triangle 5', 'NumberTitle', 'off', 'Color', '#24273a', 'Position', [600, 100, 500, 450]);
                    ax5 = axes(f5); hold(ax5, 'on'); axis(ax5, 'equal'); axis(ax5, 'off');
                    title(ax5, 'Duval Triangle 5 (High Temp Faults)', 'Color', 'w');
                    
                    % Coordinates
                    h = 100 * sqrt(3) / 2;
                    get_x = @(c2, m) c2 + 0.5 * m;
                    get_y = @(m) m * (sqrt(3) / 2);
                    to_xy = @(pts) [get_x(pts(:,1), 100 - pts(:,1) - pts(:,2)), get_y(100 - pts(:,1) - pts(:,2))];
                    
                    % Polygons (C2H4, C2H6)
                    % PD: C2H4 < 1, 2 <= C2H6 < 14
                    poly_PD = [0, 2; 1, 2; 1, 14; 0, 14];
                    
                    % S: C2H4 < 10, 14 <= C2H6 < 54
                    poly_S = [0, 14; 10, 14; 10, 54; 0, 54];
                    
                    % O: Split into Top and Bottom sections
                    poly_O_Top = [0, 0; 10, 0; 10, 14; 1, 14; 1, 2; 0, 2];
                    poly_O_Bot = [0, 54; 10, 54; 10, 90; 0, 100];
                    
                    % T2: 10 <= C2H4 < 35, C2H6 < 12
                    poly_T2 = [10, 12; 35, 12; 35, 0; 10, 0];
                    
                    % C: Complex Center
                    poly_C = [10, 30; 70, 30; 70, 14; 50, 14; 50, 12; 10, 12];
                    
                    % ND: 10 <= C2H4 < 35, C2H6 >= 30
                    poly_ND = [10, 90; 35, 65; 35, 30; 10, 30];
                    
                    % T3: High Temp
                    poly_T3 = [35, 65; 100, 0; 35, 0; 35, 12; 50, 12; 50, 14; 70, 14; 70, 30; 35, 30];
                    
                    % Zones
                    zone = @(p, c) patch(ax5, 'Vertices', to_xy(p), 'Faces', 1:size(p,1), 'FaceColor', c, 'EdgeColor', 'k', 'FaceAlpha', 0.8);
                    
                    zone(poly_T3, [0.8 0.5 0.2]); 
                    zone(poly_C,  [1.0 0.6 1.0]); 
                    zone(poly_ND, [0.7 1.0 0.7]); 
                    zone(poly_T2, [1.0 0.8 0.4]); 
                    zone(poly_S,  [0.8 0.8 1.0]); 
                    zone(poly_PD, [0.7 0.9 1.0]); 
                    zone(poly_O_Top, [1.0 1.0 0.8]); 
                    zone(poly_O_Bot, [1.0 1.0 0.8]); 
                    
                    % Triangle Outline & Labels
                    plot(ax5, [0 100 50 0], [0 0 h 0], 'k-', 'LineWidth', 2);
                    
                    txt = @(x,y,s) text(ax5, x, y, s, 'Color', 'k', 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'FontSize', 9);
                    txt(50, h-8, 'PD'); txt(48, h-12, 'O'); txt(30, 50, 'S'); txt(15, 60, 'O');
                    txt(40, 35, 'ND'); txt(60, 50, 'C'); txt(55, 15, 'T3'); txt(85, 5, 'T3'); txt(35, 5, 'T2');
                    
                    % Axis Labels
                    text(ax5, 50, h+5, '% CH_4', 'Color', 'w', 'HorizontalAlignment', 'center');
                    text(ax5, -5, 0, '% C_2H_6', 'Color', 'w', 'HorizontalAlignment', 'right');
                    text(ax5, 105, 0, '% C_2H_4', 'Color', 'w', 'HorizontalAlignment', 'left');
                    
                    % Fault Point
                    ux = get_x(p_c2h4_5, p_ch4_5);
                    uy = get_y(p_ch4_5);
                    
                    plot(ax5, ux, uy, 'ko', 'MarkerFaceColor', 'r', 'MarkerSize', 8, 'LineWidth', 1.5);
                    text(ax5, ux+2, uy, 'FAULT', 'BackgroundColor', 'w', 'EdgeColor', 'k', 'FontSize', 8);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.H2EditFieldLabel = uilabel(app.UIFigure);
            app.H2EditFieldLabel.Text = 'H2';
            app.H2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CH4EditFieldLabel = uilabel(app.UIFigure);
            app.CH4EditFieldLabel.Text = 'CH4';
            app.CH4EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2H6EditFieldLabel = uilabel(app.UIFigure);
            app.C2H6EditFieldLabel.Text = 'C2H6';
            app.C2H6EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2H4EditFieldLabel = uilabel(app.UIFigure);
            app.C2H4EditFieldLabel.Text = 'C2H4';
            app.C2H4EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2H2EditFieldLabel = uilabel(app.UIFigure);
            app.C2H2EditFieldLabel.Text = 'C2H2';
            app.C2H2EditField = uieditfield(app.UIFigure, 'numeric');
            app.COEditFieldLabel = uilabel(app.UIFigure);
            app.COEditFieldLabel.Text = 'CO';
            app.COEditField = uieditfield(app.UIFigure, 'numeric');
            app.CO2EditFieldLabel = uilabel(app.UIFigure);
            app.CO2EditFieldLabel.Text = 'CO2';
            app.CO2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.AnalyzeFaultsButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeFaultsButtonPushed, true);
            app.AnalyzeFaultsButton.Text = 'Analyze Faults';
            app.ValuesinppmLabel = uilabel(app.UIFigure);
            app.ValuesinppmLabel.Text = 'Values in ppm';
            app.DuvalTriangle1Label = uilabel(app.UIFigure);
            app.DuvalTriangle1Label.Text = 'Duval Triangle 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end